classdef blank_template < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        GridLayout  matlab.ui.container.GridLayout
        Upload      matlab.ui.control.Button
        Accept      matlab.ui.control.Button
        Image       matlab.ui.control.Image
    end

    
    methods (Access = private)
        
        function acceptBtnDisable(app)
            if ~app.Image.ImageSource
                app.Accept.Enable="off";
                return;
            end
            app.Accept.Enable="on";
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Upload
        function UploadButtonPushed(app, event)
            extensions = {'*.tif;*.png;*.jpg;*.jpeg'};
            [file,path] = uigetfile(extensions);
            if isequal(file,0)
                acceptBtnDisable(app);
                return;
            end
            [~,~,fExt]=fileparts(file);
            if ~any(strcmp({'.jpg','.jpeg','.png','.tif'},fExt))
                errordlg('Not supported file type :(');
                acceptBtnDisable(app);
                return;
            end
            img = imread(strcat(path, file));
            app.Image.ImageSource = img;
            acceptBtnDisable(app);
        end

        % Button pushed function: Accept
        function AcceptButtonPushed(app, event)
            if app.Accept.Enable=="off"
                errordlg('Not supported file type :(');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Image = uiimage(app.GridLayout);
            app.Accept = uibutton(app.GridLayout, 'push');
            app.Accept.ButtonPushedFcn = createCallbackFcn(app, @AcceptButtonPushed, true);
            app.Accept.Enable = 'off';
            app.Accept.Text = 'Accept =>';
            app.Upload = uibutton(app.GridLayout, 'push');
            app.Upload.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.Upload.Text = 'Upload';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = blank_template

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end